classdef app_aero < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        IntroTab                       matlab.ui.container.Tab
        Date03102021Label              matlab.ui.control.Label
        AuthorMariolvarezRedondoLabel  matlab.ui.control.Label
        AERODYNAMICFLOWCONFIGURATIONLabel  matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        MainTab                        matlab.ui.container.Tab
        AxesLimitsLabel                matlab.ui.control.Label
        AxesLimits                     matlab.ui.control.NumericEditField
        StreamplotCheckBox             matlab.ui.control.CheckBox
        VectorplotCheckBox             matlab.ui.control.CheckBox
        ResolucinSlider                matlab.ui.control.Slider
        ResolucinSliderLabel           matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        UniformflowmsLabel             matlab.ui.control.Label
        ActualizarButton               matlab.ui.control.Button
        RowtodeleteLabel               matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        DeleterowButton                matlab.ui.control.Button
        NewRowButton                   matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        SpecialcasesTab                matlab.ui.container.Tab
        Slider_6                       matlab.ui.control.Slider
        Slider_5                       matlab.ui.control.Slider
        Slider_4                       matlab.ui.control.Slider
        Slider_3                       matlab.ui.control.Slider
        Slider2                        matlab.ui.control.Slider
        Slider                         matlab.ui.control.Slider
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        JoukowskiTransformationTab     matlab.ui.container.Tab
        Image10                        matlab.ui.control.Image
        NmLabel                        matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        LiftforceLabel                 matlab.ui.control.Label
        VinfSlider                     matlab.ui.control.Slider
        V_infLabel                     matlab.ui.control.Label
        TollSlider                     matlab.ui.control.Slider
        TollSliderLabel                matlab.ui.control.Label
        AngleSlider                    matlab.ui.control.Slider
        AngleSliderLabel               matlab.ui.control.Label
        RadioEditField                 matlab.ui.control.NumericEditField
        RadioEditFieldLabel            matlab.ui.control.Label
        YCircleEditField               matlab.ui.control.NumericEditField
        YCircleEditFieldLabel          matlab.ui.control.Label
        XCircleEditField               matlab.ui.control.NumericEditField
        XCircleEditFieldLabel          matlab.ui.control.Label
        CargarButton                   matlab.ui.control.Button
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        MathsTab                       matlab.ui.container.Tab
        Hyperlink2                     matlab.ui.control.Hyperlink
        AndersonJD2001FundamentalsofaerodynamicsBostonMcGrawHillLabel  matlab.ui.control.Label
        BibliografaLabel               matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        Image9                         matlab.ui.control.Image
        FundamentalsofAerodynamicsAndesonLabel  matlab.ui.control.Label
        Image8                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        copyTable   % Cocia de la tabla para añadir una nueva fila
    end
    
    methods (Access = private)
%         function preview = inicialicatind(app)
%             % Inicializar valores
%         end
        
        function [u_uniform, v_uniform] = uniform(~,vu)
            u_uniform = 0;
            v_uniform = vu;
        end

        function [u_source, v_source] = source(~, X, Y, x_source, y_source, ...
                strength_source)
            u_source = (strength_source / (2 * pi) * ...
                (X - x_source) ./ ((X - x_source).^2 + (Y - y_source).^2));
            v_source = (strength_source / (2 * pi) * ...
                (Y - y_source) ./ ((X - x_source).^2 + (Y - y_source).^2));
        end

        
        function [u_sink, v_sink] = sink(~, X, Y, x_sink, y_sink, strength_sink)
            u_sink = (-strength_sink / (2 * pi) * ...
                (X - x_sink) ./ ((X - x_sink).^2 + (Y - y_sink).^2));
            v_sink = (-strength_sink / (2 * pi) * ...
                (Y - y_sink) ./ ((X - x_sink).^2 + (Y - y_sink).^2));
        end
        
        function [u, v] = vortex(~, X, Y, xv, yv, strength)
                u = +strength/(2 * pi) * (Y - yv)./((X - xv).^2 + (Y - yv).^2);
                v = -strength/(2 * pi) * (X - xv)./((X - xv).^2 + (Y - yv).^2);
        end
                
        function [u_d, v_d] = doublet(~, X, Y, xd, yd, strength_d)
            u_d = (- strength_d / (2 * pi) * ...
                ((X - xd).^2 - (Y - yd).^2) ./ ((X - xd).^2 + (Y - yd).^2).^2);
            v_d = (- strength_d / (2 * pi) * ...
                 2 * (X - xd) .* (Y - yd) ./ ((X - xd).^2 + (Y - yd).^2).^2);
        end
        
        function results = grad2rad(~, alpha)
            results = alpha * pi / 180;
        end

        function [J,f, z, z_circle, z_airfoil, L_str] = joukowski(app, v_inf,...
                alpha, x_cir, y_cir, radio, toll)
            
            r = radio;
            v = v_inf/v_inf;
            theta = grad2rad(app,alpha);
            s = x_cir + y_cir * 1i;
            
            % FLUID PARAMETER
            rho = 1.225;
            % TRANSFORMATION PARAMETER
            lambda = r-s;
            % CIRCULATION
            beta = (theta);
            k = 2*r*v*sin(beta);
            Gamma = k/(2*pi); %CIRCULATION

            %COMPLEX ASYMPTOTIC SPEED 
            w = v * exp(1i*theta);
            x = meshgrid(-3:.01:3);
            y = x';

            % COMPLEX PLANE
            z = x + 1i*y;

            for a = 1:length(x)
                for b = 1:length(y)
                    if abs(z(a,b)-s) <=  r - toll
                        z(a,b) = NaN;
                    end
                end
            end
            % AERODYNAMIC POTENTIAL
            f = w*(z) + (v*exp(-1i*theta)*r^2)./(z-s) + 1i*k*log(z);

            % JOUKOWSKI TRANSFORMATION, 
            J = z+lambda^2./z;

            %GRAPHIC - Circle and Joukowski Airfoil
            angle = 0:.1:2*pi;
            z_circle = r*(cos(angle)+1i*sin(angle)) + s;
            z_airfoil = z_circle+lambda^2./z_circle;
            
            % KUTTA JOUKOWSKI THEOREM
            L = v_inf*rho*Gamma;
            L_str = num2str(L);
            
        end
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable.ColumnFormat = {{'Source', 'Sink', 'Vortex', 'Doublet'}, ...
               'numeric', 'numeric', 'numeric', 'logical'};
           
           app.UITable.Data = cell(2,5);
           app.UITable.Data(1,:) = {'Source', 0, 0, 10, true};
           app.UITable.Data(2,:) = {'Sink', 2, 0, 10, false};
            
           lim = app.AxesLimits.Value;
           app.UIAxes.XLim = [-lim, lim];
           app.UIAxes.YLim = [-lim, lim];
           app.UIAxes.XGrid ='On';
           app.UIAxes.YGrid ='On';
           
           app.ResolucinSlider.Limits = [0.0001,0.6];
        end

        % Button pushed function: NewRowButton
        function NewRowButtonPushed(app, event)
            [m, n] = size(app.UITable.Data);
            app.copyTable = app.UITable.Data;
            d = cell(m + 1, n);
            d(1:m,1:n) = app.UITable.Data;
            app.UITable.Data = d;

            app.UITable.Data(m + 1, :) = {'Vortex', 0, 0, 10, false};
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            [m, ~] = size(app.UITable.Data);
            d = app.EditField.Value;
            disp(d)
            whos d
            if (0 < m) && (m >= app.EditField.Value)
                d = app.EditField.Value;
                app.UITable.Data(d,:) = [];
            else 
                warning('Value out of range')
            end

        end

        % Callback function: ActualizarButton, AxesLimits, EditField_2, 
        % ResolucinSlider, Slider_4, Slider_6
        function ActualizarButtonPushed(app, event)
            cla(app.UIAxes)
            
            % Ejes
            lim = app.AxesLimits.Value;
            app.UIAxes.XLim = [-lim, lim];
            app.UIAxes.YLim = [-lim* 0.5, lim*0.5];

            % Slider
            value_s = app.ResolucinSlider.Value;
            % Extracción de los datos de la table

            flow_type = app.UITable.Data(:,1);
            coorx = cell2mat(app.UITable.Data(:, 2));
            coory = cell2mat(app.UITable.Data(:, 3));
            strenght = cell2mat(app.UITable.Data(:, 4));
            activated = cell2mat(app.UITable.Data(:, 5));
            

            flow_type = string(flow_type);
            % Definición de la malla donde se va a evaluar
            [X, Y] = meshgrid(-lim:value_s:lim);

            % Obteción de campo de velocidades
            [m, ~] = size(X);
            valor = app.EditField_2.Value;
            valor = str2double(valor);

            v = zeros(m);
            u = valor * ones(m);
            
            contador = 1;

            for ii = 1:length(flow_type)
                if activated(ii) == 1
                    switch flow_type(ii)
                        case "Source"
                            [u1, v1] = source(app, X,Y, coorx(contador), ...
                                coory(contador), strenght(contador));
                            
                        case "Sink"
                            [u1, v1] = sink(app, X,Y, coorx(contador), ...
                                coory(contador), strenght(contador));
                            
                        case "Vortex"
                            [u1, v1] = vortex(app, X,Y, coorx(contador), ...
                                coory(contador), strenght(contador));
                            
                        case "Doublet"
                            [u1, v1] = doublet(app, X,Y, coorx(contador), ...
                                coory(contador), strenght(contador));
                            
                    end
                    
                    u = u1 + u;
                    v = v1 + v;
                end
                contador = contador + 1;
            end
            
 
            if (app.StreamplotCheckBox.Value == 1) && ...
                    (app.VectorplotCheckBox.Value == 0)
                st = streamslice(app.UIAxes, X, Y, u, v);
                set(st,'LineWidth',1);
                set(st,'Color','b');
                set(app.UIAxes,'DataAspectRatio',[1 1 2])

            elseif (app.StreamplotCheckBox.Value == 0) && ...
                    (app.VectorplotCheckBox.Value == 1)
                q = quiver(app.UIAxes, X, Y, u, v);
                q.Marker = '.';
                q.Color = 'r';
                q.LineWidth = 0.8;
                q.AutoScaleFactor = 1.4;

            else
                q = quiver(app.UIAxes, X, Y, u, v);
                q.Marker = '.';
                q.Color = 'r';
                q.LineWidth = 0.8;
                q.AutoScaleFactor = 1.4;
                st = streamslice(app.UIAxes, X, Y, u, v);
                set(st,'LineWidth',1);
                set(st,'Color','b');
                
            end

        end

        % Callback function: Slider, Slider2, Slider_3, Slider_4, Slider_5, 
        % Slider_5, Slider_6, SpecialcasesTab
        function SpecialcasesTabButtonDown(app, event)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)

            [X, Y] = meshgrid(-4:0.1:4);
   
            % Priemra gráfica

            [u1,v1] = source(app, X, Y, 0, 2, 10);
            [u2,v2] = source(app, X, Y, 0, -2, 10);

            u_ax1 = u1 + u2 + app.Slider.Value;
            v_ax1 = v1 + v2;

            app.UIAxes2.XLim = [-4, 4];
            app.UIAxes2.YLim = [-3, 3];

            st = streamslice(app.UIAxes2, X, Y, u_ax1, v_ax1);
            set(st,'LineWidth',1);
            set(st,'Color',[0 , 0.8, 0.8]);
            set(app.UIAxes2,'DataAspectRatio',[1 1 1]);
            app.UIAxes2.YGrid = 'On';

            % Segunda gráfica
            [u11,v11] = source(app, X, Y, -2, 0, app.Slider2.Value);
            [u21,v21] = sink(app, X, Y, 2, 0, app.Slider_3.Value);

            u_ax2 = u11 + u21;
            v_ax2 = v11 + v21;

            app.UIAxes3.XLim = [-4, 4];
            app.UIAxes3.YLim = [-3, 3];

            st = streamslice(app.UIAxes3, X, Y, u_ax2, v_ax2);
            set(st,'LineWidth',1);
            set(st,'Color',[0 , 0.4, 0.8]);
            set(app.UIAxes3,'DataAspectRatio',[1 1 1]);
            app.UIAxes3.YGrid = 'On';

            % Tercera gráfica
            [u12,v12] = doublet(app, X, Y, 0, 0, app.Slider_4.Value);
            [u22,v22] = vortex(app, X, Y, 0, 0, app.Slider_5.Value);

            u_ax3 = u12 + u22 + 4;
            v_ax3 = v12 + v22;

            app.UIAxes4.XLim = [-4, 4];
            app.UIAxes4.YLim = [-3, 3];

            st = streamslice(app.UIAxes4, X, Y, u_ax3, v_ax3);
            set(st,'LineWidth',1);
            set(st,'Color',[0.8 , 0.4, 0.2]);
            set(app.UIAxes4,'DataAspectRatio',[1 1 1]);
            app.UIAxes4.YGrid = 'On';

            % Cuarta gráfica
            [u13,v13] = doublet(app, X, Y, 0, 0, app.Slider_6.Value);

            u_ax4 = u13 + 4;
            v_ax4 = v13 ;

            app.UIAxes5.XLim = [-4, 4];
            app.UIAxes5.YLim = [-3, 3];

            st = streamslice(app.UIAxes5, X, Y, u_ax4, v_ax4);
            set(st,'LineWidth',1);
            set(st,'Color',[0.2 , 0.8, 0.2]);
            set(app.UIAxes5,'DataAspectRatio',[1 1 1]);
            app.UIAxes5.YGrid = 'On';
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            cla(app.UIAxes6)
            cla(app.UIAxes7)
            
            v_inf = app.VinfSlider.Value;
            alpha = app.AngleSlider.Value;
            x_cir = app.XCircleEditField.Value;
            y_cir = app.YCircleEditField.Value;
            radio = app.RadioEditField.Value;
            toll = app.TollSlider.Value;
            [J,f, z, z_circle, z_airfoil, L_str] = joukowski(app, v_inf,...
                alpha, x_cir, y_cir, radio, toll);
            
            ma = -5:.2:5;
            
            
            contourf(app.UIAxes6, real(z), imag(z), imag(f), ma)
            hold(app.UIAxes6, 'on')
            fill(app.UIAxes6, real(z_circle), imag(z_circle),'k')
            set(app.UIAxes6,'DataAspectRatio',[1 1 1])
            axis(app.UIAxes6,[-3 3 -3 3])
            hold(app.UIAxes6, 'off')

            % title(strcat('Flow Around a Circle.   Lift:  ',L_str,'  [N/m]'));

            contourf(app.UIAxes7,real(J),imag(J),imag(f), ma)
            hold(app.UIAxes7, 'on')
            fill(app.UIAxes7,real(z_airfoil),imag(z_airfoil),'k')
            set(app.UIAxes7,'DataAspectRatio',[1 1 1])
            axis(app.UIAxes7, [-3 3 -3 3])
            hold(app.UIAxes7, 'off')
            
            app.EditField_3.Value = L_str;
            % title(strcat('Flow Around the Corresponding Airfoil.   Lift:  ',L_str,'  [N/m]'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Image5 = uiimage(app.IntroTab);
            app.Image5.ImageSource = 'aerodinamica_aviones.png';
            app.Image6 = uiimage(app.IntroTab);
            app.Image6.ImageSource = 'escudo.svg';
            app.AERODYNAMICFLOWCONFIGURATIONLabel = uilabel(app.IntroTab);
            app.AERODYNAMICFLOWCONFIGURATIONLabel.Text = 'AERODYNAMIC FLOW CONFIGURATION';
            app.AuthorMariolvarezRedondoLabel = uilabel(app.IntroTab);
            app.AuthorMariolvarezRedondoLabel.Text = 'Author: Mario Álvarez Redondo';
            app.Date03102021Label = uilabel(app.IntroTab);
            app.Date03102021Label.Text = 'Date: 03/10/2021';
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'Aerodynamic flow')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.MainTab);
            app.UITable.ColumnName = {'Element'; 'X'; 'Y'; 'Power'; 'Activated'};
            app.UITable.ColumnSortable = [true false false false true];
            app.UITable.ColumnEditable = true;
            app.NewRowButton = uibutton(app.MainTab, 'push');
            app.NewRowButton.ButtonPushedFcn = createCallbackFcn(app, @NewRowButtonPushed, true);
            app.NewRowButton.Text = 'New Row';
            app.DeleterowButton = uibutton(app.MainTab, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.EditField = uieditfield(app.MainTab, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.Value = 1;
            app.RowtodeleteLabel = uilabel(app.MainTab);
            app.RowtodeleteLabel.Text = 'Row to delete';
            app.ActualizarButton = uibutton(app.MainTab, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.UniformflowmsLabel = uilabel(app.MainTab);
            app.UniformflowmsLabel.Text = 'Uniform flow (m/s)';
            app.EditField_2 = uieditfield(app.MainTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.EditField_2.Placeholder = '10';
            app.EditField_2.Value = '4';
            app.ResolucinSliderLabel = uilabel(app.MainTab);
            app.ResolucinSliderLabel.Text = 'Resolución';
            app.ResolucinSlider = uislider(app.MainTab);
            app.ResolucinSlider.Limits = [1e-05 0.6];
            app.ResolucinSlider.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ResolucinSlider.Value = 0.2;
            app.VectorplotCheckBox = uicheckbox(app.MainTab);
            app.VectorplotCheckBox.Text = 'Vector plot';
            app.StreamplotCheckBox = uicheckbox(app.MainTab);
            app.StreamplotCheckBox.Text = 'Stream plot';
            app.StreamplotCheckBox.Value = true;
            app.AxesLimits = uieditfield(app.MainTab, 'numeric');
            app.AxesLimits.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.AxesLimits.Value = 4;
            app.AxesLimitsLabel = uilabel(app.MainTab);
            app.AxesLimitsLabel.Text = 'Axes Limits';
            app.SpecialcasesTab = uitab(app.TabGroup);
            app.SpecialcasesTab.Title = 'Special cases';
            app.SpecialcasesTab.ButtonDownFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.UIAxes2 = uiaxes(app.SpecialcasesTab);
            title(app.UIAxes2, 'Ground effect')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.SpecialcasesTab);
            title(app.UIAxes3, 'Source / Sink pair')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.SpecialcasesTab);
            title(app.UIAxes4, 'Lifting cylinder')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.SpecialcasesTab);
            title(app.UIAxes5, 'Non lifting cylinder')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Slider = uislider(app.SpecialcasesTab);
            app.Slider.Limits = [0 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider.Value = 10;
            app.Slider2 = uislider(app.SpecialcasesTab);
            app.Slider2.Limits = [0 20];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider2.Value = 10;
            app.Slider_3 = uislider(app.SpecialcasesTab);
            app.Slider_3.Limits = [0 20];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider_3.Value = 10;
            app.Slider_4 = uislider(app.SpecialcasesTab);
            app.Slider_4.Limits = [0 50];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider_4.Value = 20;
            app.Slider_5 = uislider(app.SpecialcasesTab);
            app.Slider_5.Limits = [0 50];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider_5.Value = 30;
            app.Slider_6 = uislider(app.SpecialcasesTab);
            app.Slider_6.Limits = [0 50];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @SpecialcasesTabButtonDown, true);
            app.Slider_6.Value = 30;
            app.JoukowskiTransformationTab = uitab(app.TabGroup);
            app.JoukowskiTransformationTab.Title = 'Joukowski Transformation';
            app.UIAxes6 = uiaxes(app.JoukowskiTransformationTab);
            title(app.UIAxes6, 'Flow arround a cylinder')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.JoukowskiTransformationTab);
            title(app.UIAxes7, 'Flow arround Joukowski airfoil')
            zlabel(app.UIAxes7, 'Z')
            app.CargarButton = uibutton(app.JoukowskiTransformationTab, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.XCircleEditFieldLabel = uilabel(app.JoukowskiTransformationTab);
            app.XCircleEditFieldLabel.Text = 'X (Circle)';
            app.XCircleEditField = uieditfield(app.JoukowskiTransformationTab, 'numeric');
            app.YCircleEditFieldLabel = uilabel(app.JoukowskiTransformationTab);
            app.YCircleEditFieldLabel.Text = 'Y (Circle)';
            app.YCircleEditField = uieditfield(app.JoukowskiTransformationTab, 'numeric');
            app.RadioEditFieldLabel = uilabel(app.JoukowskiTransformationTab);
            app.RadioEditFieldLabel.Text = 'Radio';
            app.RadioEditField = uieditfield(app.JoukowskiTransformationTab, 'numeric');
            app.RadioEditField.Value = 0.35;
            app.AngleSliderLabel = uilabel(app.JoukowskiTransformationTab);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.JoukowskiTransformationTab);
            app.AngleSlider.Limits = [0 18];
            app.AngleSlider.Value = 8;
            app.TollSliderLabel = uilabel(app.JoukowskiTransformationTab);
            app.TollSliderLabel.Text = 'Toll';
            app.TollSlider = uislider(app.JoukowskiTransformationTab);
            app.TollSlider.Limits = [1e-05 0.01];
            app.TollSlider.Value = 0.005;
            app.V_infLabel = uilabel(app.JoukowskiTransformationTab);
            app.V_infLabel.Text = 'V inf';
            app.VinfSlider = uislider(app.JoukowskiTransformationTab);
            app.VinfSlider.Limits = [0 40];
            app.VinfSlider.Value = 10;
            app.LiftforceLabel = uilabel(app.JoukowskiTransformationTab);
            app.LiftforceLabel.Text = 'Lift force:';
            app.EditField_3 = uieditfield(app.JoukowskiTransformationTab, 'text');
            app.NmLabel = uilabel(app.JoukowskiTransformationTab);
            app.NmLabel.Text = 'N/m';
            app.Image10 = uiimage(app.JoukowskiTransformationTab);
            app.Image10.ImageSource = 'logo_ingenierias_retina.png';
            app.MathsTab = uitab(app.TabGroup);
            app.MathsTab.Title = 'Maths';
            app.Image7 = uiimage(app.MathsTab);
            app.Image7.ImageSource = '1.png';
            app.Image8 = uiimage(app.MathsTab);
            app.Image8.ImageSource = '2.png';
            app.FundamentalsofAerodynamicsAndesonLabel = uilabel(app.MathsTab);
            app.FundamentalsofAerodynamicsAndesonLabel.Text = 'Fundamentals of Aerodynamics (Andeson)';
            app.Image9 = uiimage(app.MathsTab);
            app.Image9.ImageSource = '3.png';
            app.Hyperlink = uihyperlink(app.MathsTab);
            app.Hyperlink.URL = 'https://es.mathworks.com/matlabcentral/fileexchange/65430-matlab-program-for-joukowski-airfoil';
            app.Hyperlink.Text = 'VBBV (2021). Matlab program for Joukowski Airfoil (https://www.mathworks.com/matlabcentral/fileexchange/65430-matlab-program-for-joukowski-airfoil), MATLAB Central File Exchange. Retrieved October 5, 2021.';
            app.BibliografaLabel = uilabel(app.MathsTab);
            app.BibliografaLabel.Text = 'Bibliografía';
            app.AndersonJD2001FundamentalsofaerodynamicsBostonMcGrawHillLabel = uilabel(app.MathsTab);
            app.AndersonJD2001FundamentalsofaerodynamicsBostonMcGrawHillLabel.Text = 'Anderson, J. D. (2001). Fundamentals of aerodynamics. Boston: McGraw-Hill.';
            app.Hyperlink2 = uihyperlink(app.MathsTab);
            app.Hyperlink2.URL = 'https://es.wikipedia.org/wiki/Transformada_de_Joukowsky';
            app.Hyperlink2.Text = 'Transformada de Joukowsky 22 sep 2021 en Wikipedia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_aero

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end